classdef trial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CurrentwindowsizeLabel  matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        InputImageButton        matlab.ui.control.Button
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            sizes = [8, 16, 32];
            val = round(app.Slider.Value / 8); % Since slider value is 8, 16, or 32
            sizes = [8, 16, 32];
            app.CurrentwindowsizeLabel.Text = sprintf('%dx%d', sizes(val), sizes(val));
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            if isempty(app.OriginalImage)
                return;
            end
            sizes = [8, 16, 32];
            winSize = sizes(round(app.Slider.Value / 8));
            enhanced = app.local_hist_eq(app.OriginalImage, winSize);
            imshow(enhanced, 'Parent', app.UIAxes3);
            histogram(enhanced, 'Parent', app.UIAxes4);
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select an image');
        if isequal(file, 0)
            return; % User canceled
        end

        img = imread(fullfile(path, file));
        if size(img, 3) == 3
            img = rgb2gray(img); % Convert to grayscale if RGB
        end

        app.OriginalImage = img;

    % Show original image and histogram
        imshow(img, 'Parent', app.UIAxes);
        histogram(img, 'Parent', app.UIAxes2);

    % Automatically trigger the update for the enhanced image
        sizes = [8, 16, 32];
        sliderVal = app.Slider.Value;
        [~, idx] = min(abs(sizes - sliderVal)); % Snap to closest
        winSize = sizes(idx);

        enhanced = app.local_hist_eq(img, winSize);

        imshow(enhanced, 'Parent', app.UIAxes3);
        histogram(enhanced, 'Parent', app.UIAxes4);

    % Update label
        app.CurrentwindowsizeLabel.Text = sprintf('%dx%d', winSize, winSize);
        end

        % Button down function: UIAxes
        function UIAxes3ButtonDown2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown2, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original Histogram')
            xlabel(app.UIAxes2, 'Intensity level')
            ylabel(app.UIAxes2, 'Frequency')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Enhanced Image')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Enhanced Histogram')
            xlabel(app.UIAxes4, 'Intensity level')
            ylabel(app.UIAxes4, 'Frequency')
            app.InputImageButton = uibutton(app.UIFigure, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 32];
            app.Slider.MajorTicks = [0 8 16 32];
            app.Slider.MajorTickLabels = {'0,8,16,32'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 16;
            app.CurrentwindowsizeLabel = uilabel(app.UIFigure);
            app.CurrentwindowsizeLabel.Text = 'Current window size';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end